classdef OrientationAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ExportDataButton               matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UITable                        matlab.ui.control.Table
        SpectraTypeDropDownLabel       matlab.ui.control.Label
        DropDown_SpectraType           matlab.ui.control.DropDown
        BrushDataPanel                 matlab.ui.container.Panel
        ReadBrushButton                matlab.ui.control.Button
        DeleteRowButton                matlab.ui.control.Button
        ClearallButton                 matlab.ui.control.Button
        IntensityPlotButton            matlab.ui.control.Button
        OrientationMapPanel            matlab.ui.container.Panel
        SelectedLabel                  matlab.ui.control.Label
        SelectedIndex                  matlab.ui.control.EditField
        GridEditFieldLabel             matlab.ui.control.Label
        GridEditField                  matlab.ui.control.NumericEditField
        PhiSliceEditFieldLabel         matlab.ui.control.Label
        PhiSliceEditField              matlab.ui.control.EditField
        PsiSliceEditFieldLabel         matlab.ui.control.Label
        PsiSliceEditField              matlab.ui.control.EditField
        ThetaSliceEditFieldLabel       matlab.ui.control.Label
        ThetaSliceEditField            matlab.ui.control.EditField
        PhiContourSliceEditFieldLabel  matlab.ui.control.Label
        PhiContourSliceEditField       matlab.ui.control.EditField
        PsiContourSliceEditFieldLabel  matlab.ui.control.Label
        PsiContourSliceEditField       matlab.ui.control.EditField
        ThetaContourSliceEditFieldLabel  matlab.ui.control.Label
        ThetaContourSliceEditField     matlab.ui.control.EditField
        IsovaluesEditFieldLabel        matlab.ui.control.Label
        IsovaluesEditField             matlab.ui.control.EditField
        V10Label                       matlab.ui.control.Label
        DrawMapButton                  matlab.ui.control.Button
        StyleDropDownLabel             matlab.ui.control.Label
        StyleDropDown                  matlab.ui.control.DropDown
    end


    properties (Access = public)
        Parent % handles of parent
        CurrentFig
        Data_Brush
        Data_Spec % StructureData Class
        Response_V  
        SelectedRow
        IntGrid
        N_Brished % Description
    end

    methods (Access = private)
    
        function Update_OrientTable(app)
            %% Reorginze Data_Brush
            Data_Table = reshape([app.Data_Brush(:).TableRow],3,[])';
            Row_Color  = reshape([app.Data_Brush(:).Color],3,[])';
            
            %% Initial vlues of the Orientation inputs
            ColumnName     = {       '', 'Intensity', 'Ratio'};
            ColumnFormat   = {'logical',      'char',  'bank'};
            ColumnEditable = [     true,       false,    true];
            ColumnWidth    = {       20,         70,      40};
            
            app.UITable.ColumnName      = ColumnName;
            app.UITable.ColumnFormat    = ColumnFormat;
            app.UITable.ColumnEditable  = ColumnEditable;
            app.UITable.ColumnWidth     = ColumnWidth;
            app.UITable.RowName         = 'numbered';
            app.UITable.BackgroundColor = Row_Color;
            app.UITable.Data            = Data_Table;
        end
        
        function Read_SpecData(app)
            SpecType = app.DropDown_SpectraType.Value;
            switch SpecType
                case 'SFG'
                    app.Data_Spec = app.Parent.Data_SFG;
                case '2DSFG'
                    app.Data_Spec = app.Parent.Data_2DSFG;
            end
        end
        
    end

    methods (Access = public)
    
        function O = Parse_GUI(app)
            O.PhiS    = str2num(app.PhiSliceEditField.Value);
            O.PsiS    = str2num(app.PsiSliceEditField.Value);
            O.ThetaS  = str2num(app.ThetaSliceEditField.Value);
            O.PsiCS   = str2num(app.PsiContourSliceEditField.Value);
            O.PhiCS   = str2num(app.PhiContourSliceEditField.Value);
            O.ThetaCS = str2num(app.ThetaContourSliceEditField.Value);
            O.IsoV    = str2num(app.IsovaluesEditField.Value);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                AssignParent(app,varargin)
                TriggerSource = varargin{3};
            else
                TriggerSource = 'SFG';
                disp('Running Orientation Analysis in the stand alone mode...')
            end
            
            % Initalized trigger source 
            app.DropDown_SpectraType.Value = TriggerSource;
            
            % initialize the UIAxes and Table
            ClearAll_ButtonPushed(app,'')
        end

        % Button pushed function: IntensityPlotButton
        function IntensityPlot_ButtonPushed(app, event)
            Read_SpecData(app)
            Spec = app.Data_Spec;
            
            COSMOSS_Input = app.Parent.Parse_GUI;
            COSMOSS_Input.PlotNorm  = 1;
            
            app.CurrentFig = figure;
            hAx = axes('Parent',app.CurrentFig);
            
            SpecType = app.DropDown_SpectraType.Value;
            switch SpecType
                case 'SFG'
                    COSMOSS_Input.Lineshape = 'Lorentzian';
                    
                    Spec.Response1D = Spec.Res_Int;
                    Plot1D(hAx,Spec,COSMOSS_Input);
                    
                    ScatterX = Spec.freq_OneD;
                    ScatterY = Spec.Res_Int;
                    ScatterX(eq(ScatterY,0)) = [];
                    ScatterY(eq(ScatterY,0)) = [];
                    
                    hold(hAx,'on')
                        X = ScatterX;
                        Y = ScatterY;
                        Y = Y./max(abs(Y(:)));
                        scatter(hAx,X,Y,100,'filled','MarkerFaceColor',[0,0,0])
                    hold(hAx,'off')   
                    hAx.YLim = [0,1.1];
                case '2DSFG'
                    COSMOSS_Input.Lineshape = 'No Conv';
                    CVL = Conv2D(Spec.Response.IntGrid,COSMOSS_Input);
                    CVL.FilesName = 'Response intensity plot'; % pass filesname for figure title
                    Plot2D(hAx,CVL,COSMOSS_Input,'2DSFG Int');
                    
                    hAx.Color = [0.5,0.5,0.5];
                    colormap(hAx,'hot')
                    caxis(hAx,[0,1])
            end
            
        end

        % Button pushed function: ExportDataButton
        function ExportData_ButtonPushed(app, event)
            Data_Orient.Data_Spec   = app.Data_Spec;
            Data_Orient.Data_Brush  = app.Data_Brush;
            Data_Orient.Response_V  = app.Response_V;
            Data_Orient.Data_Table  = app.UITable.Data;
            Data_Orient.SelectedRow = app.SelectedRow;
            Data_Orient.app         = app;
            
            assignin('base','Data_Orient',Data_Orient)
            disp('Updated Data_Orient exported!')
        end

        % Button pushed function: ReadBrushButton
        function ReadBrush_ButtonPushed(app, event)
            N = app.N_Brished + 1; % update counter
            app.N_Brished = N;
            
            % Read brush data from the Intensity Plot
            hF = app.CurrentFig;
            hS = findobj(hF,'Type','Scatter');
            hAx = findobj(hF,'Type','Axes');
            
            
            BrushedIdx = logical(hS.BrushData);
            SpecType = app.DropDown_SpectraType.Value;
            switch SpecType
                case 'SFG'
                    app.UIAxes.XLim = hAx.XLim;
                    Sort_Ex_F1 = app.Data_Spec.H.Sort_Ex_F1;
                    if iscolumn(Sort_Ex_F1)
                        Sort_Ex_F1 = Sort_Ex_F1';
                    end
                    FreqSub     = hS.XData(BrushedIdx)';
                    [~,FreqInd] = min(abs( bsxfun(@minus,FreqSub,Sort_Ex_F1) ),[],2);
                    Beta = app.Data_Spec.MolFrame(:,FreqInd);
                    Beta = sum(Beta,2);
                    
                    Scatter_X = hS.XData(BrushedIdx)';
                    Scatter_Y = zeros(size(Scatter_X));
                    MarkerSize = 150;
                case '2DSFG'
                    app.UIAxes.XLim = hAx.XLim;
                    app.UIAxes.YLim = hAx.YLim;
                    
                    FreqSub    = [hS.YData(BrushedIdx)',hS.XData(BrushedIdx)']; % [Pump,Probe]
                    SparseMax  = app.Data_Spec.Response.SparseMax;
                    FreqInd    = sub2ind([SparseMax,SparseMax],FreqSub(:,1),FreqSub(:,2));
                    
                    % Update the Brushed response
                    Beta =        app.Data_Spec.Response.Beta.R1(FreqInd,:);
                    Beta = [Beta; app.Data_Spec.Response.Beta.R2(FreqInd,:);];
                    Beta = [Beta;-app.Data_Spec.Response.Beta.R3(FreqInd,:);];
                    Beta = [Beta; app.Data_Spec.Response.Beta.NR1(FreqInd,:);];
                    Beta = [Beta; app.Data_Spec.Response.Beta.NR2(FreqInd,:);];
                    Beta = [Beta;-app.Data_Spec.Response.Beta.NR3(FreqInd,:);];
                    Beta = sum(Beta,1)';
                    
                    Scatter_X = hS.XData(BrushedIdx)';
                    Scatter_Y = hS.YData(BrushedIdx)';
                    MarkerSize = 150;
            end
            app.Response_V(:,N)= Beta;
            
            % update new info of table row
            Int  = full(sqrt(sum(Beta.^2)));
            TableRow = {true,sprintf('%3.2e',Int),1};
            BrushedColor = app.UIAxes.ColorOrder(N,:);
            
            % Update the UIAxes on GUI
            hold(app.UIAxes,'on')
                hScatter = scatter(app.UIAxes,...
                                   Scatter_X,Scatter_Y,...
                                   MarkerSize,...
                                   'filled',...
                                   'MarkerFaceColor',BrushedColor);
            hold(app.UIAxes,'off')
            
            % export Data_Brush to app and update the UITable
            app.Data_Brush(N).BrushedIdx = BrushedIdx;
            app.Data_Brush(N).FreqSub    = FreqSub;
            app.Data_Brush(N).FreqInd    = FreqInd;
            app.Data_Brush(N).Color      = BrushedColor;
            app.Data_Brush(N).hScatter   = hScatter;
            app.Data_Brush(N).TableRow   = TableRow;
            Update_OrientTable(app)
        end

        % Button pushed function: ClearallButton
        function ClearAll_ButtonPushed(app, event)
            % Reset other variable
            app.N_Brished  = 0;
            app.Response_V = [];
            
            % clean up Data_Brush
            app.Data_Brush = [];
            app.Data_Brush.BrushedIdx = [];
            app.Data_Brush.FreqSub    = [];
            app.Data_Brush.FreqInd    = [];
            app.Data_Brush.Color      = [1,1,1];
            app.Data_Brush.hScatter   = [];
            app.Data_Brush.TableRow   = {'','',''};
            Update_OrientTable(app)
            
            % make default figure
            cla(app.UIAxes,'reset')
            SpecType = app.DropDown_SpectraType.Value;
            Read_SpecData(app)

            COSMOSS_Input = app.Parent.Parse_GUI;
            COSMOSS_Input.PlotCursor = 0;
            COSMOSS_Input.PlotNorm   = 1;
            COSMOSS_Input.Lineshape  = 'Lorentzian';
            
            Read_SpecData(app)
            switch SpecType
                case 'SFG'
                    Plot1D(app.UIAxes,app.Data_Spec,COSMOSS_Input);
                case '2DSFG'
                    COSMOSS_Input.CMAP_Index = 'JetWhiteWide';
                    
                    CVL = Conv2D(app.Data_Spec.SpectraGrid,COSMOSS_Input);
                    CVL.FilesName = app.Parent.Structure.FilesName; % pass filesname for figure title
                    Plot2D(app.UIAxes,CVL,COSMOSS_Input,app.Data_Spec.Response.SpecType);
                    
                    F_Array = COSMOSS_Input.F_Min:COSMOSS_Input.F_Max;
                    app.UIAxes.XLim = [F_Array(1),F_Array(end)];
                    app.UIAxes.YLim = [F_Array(1),F_Array(end)];
                    app.UIAxes.Title.String = '';
                    colorbar(app.UIAxes,'off')
            end
            % reset color order
            app.UIAxes.ColorOrderIndex = 1;
        end

        % Button pushed function: DeleteRowButton
        function DeleteRow_ButtonPushed(app, event)
            Delete_Ind = app.SelectedRow;
            % update counter
            app.N_Brished = app.N_Brished - length(Delete_Ind);
            % delete scatter plot obj
            delete(app.Data_Brush(Delete_Ind).hScatter)
            % update Data_Brush
            app.Data_Brush(Delete_Ind) = [];
            Update_OrientTable(app)
        end

        % Button pushed function: DrawMapButton
        function DrawMap_ButtonPushed(app, event)
            COSMOSS_Input = app.Parent.Parse_GUI;
            
            SpecType = app.DropDown_SpectraType.Value;
            Orientation_Input.SpecType = SpecType;
            Orientation_Input.N_Grid   = app.GridEditField.Value;
            EJLR_T = EJLR_Tensor(COSMOSS_Input,Orientation_Input);
            
            %Selection_Ind = app.SelectedRow;
            Selection_Ind = str2num(app.SelectedIndex.Value);
            app.IntGrid   = EJLR_T * sum(app.Response_V(:,Selection_Ind),2);
            
            % visulize
            N_Grid = Orientation_Input.N_Grid;
            Phi   = linspace(0,360,N_Grid*2)'; % phi
            Theta = linspace(0,180,N_Grid)'  ; % theta
            Psi   = linspace(0,360,N_Grid*2)'; % psi
            
            % Since "EJLR_Grid" use [Phi,Theta,Psi] = meshgrid(phi,theta,psi);
            V = reshape(app.IntGrid,length(Theta),length(Phi),length(Psi)); % [Theta,Phi,Psi]
            maxV = max(abs(V(:)));
            V_N = V./maxV;
            
            OrientProj = app.StyleDropDown.Value;
            O = Parse_GUI(app);
            
            hF  = figure;
            hAx = axes('Parent',hF);
            
            [CMAP,~] = SelectColormap(app.Parent.Parse_GUI.CMAP_Index);
            colormap(hAx,CMAP)
            switch OrientProj
                case 'Theta-Psi'
                    [~,CutAngleInd] = min(abs((Phi-O.PhiS)));
                    X = Psi;
                    Y = Theta;
                    Z = squeeze(V_N(:,CutAngleInd,:));
                    contourf(hAx,X,Y,Z,20)
                    
                    XLabel = '\psi';
                    YLabel = '\theta';
                    IndexStr    = [' of selection #: ',app.SelectedIndex.Value];
                    ProjectStr  = [' (',XLabel,',',YLabel,')'];
                    CutAngleStr = [' map cut at \phi=',num2str(O.PhiS),'^{\circ}'];
                    TitleStr = [SpecType,ProjectStr,CutAngleStr,IndexStr];
                    
                case 'Theta-Phi'
                    [~,CutAngleInd] = min(abs((Psi-O.PsiS)));
                    X = Phi;
                    Y = Theta;
                    Z = squeeze(V_N(:,:,CutAngleInd));
                    contourf(hAx,X,Y,Z,20)
                    
                    XLabel = '\phi';
                    YLabel = '\theta';
                    IndexStr    = [' of selection #: ',app.SelectedIndex.Value];
                    ProjectStr  = [' (',XLabel,',',YLabel,')'];
                    CutAngleStr = [' map cut at \psi=',num2str(O.PsiS),'^{\circ}'];
                    TitleStr = [SpecType,ProjectStr,CutAngleStr,IndexStr];
                    
                case 'Slice'
                    cvals = linspace(-1,1,20);
                    
                    hold(hAx,'on')
                        % Slice
                        hSlice = slice(hAx,Phi,Theta,Psi,V_N,O.PhiS ,O.ThetaS ,O.PsiS);
                        N_Slice = length(hSlice);
                        for j = 1:N_Slice
                            hSlice(j).EdgeColor = 'none';
                            hSlice(j).FaceAlpha = 0.5;
                        end
                        
                        % Contour Slcie
                        contourslice(hAx,Phi,Theta,Psi,V_N,O.PhiCS,O.ThetaCS,O.PsiCS,cvals)
                        
                        % isosurf
                        N_IsoV = length(O.IsoV);
                        if ~eq(N_IsoV,0)
                            CV = linspace(-1,1,64);
                            for i =1:N_IsoV
                                IsoV = O.IsoV(i);
                                [~,IsoColorInd] = min(abs(CV-IsoV));
                                IsoColor = CMAP(IsoColorInd,:);
                                
                                fv = isosurface(Phi,Theta,Psi,V_N,IsoV);
                                P = patch(hAx,fv,...
                                         'FaceColor',IsoColor,...
                                         'FaceAlpha',abs(IsoV),...
                                         'EdgeColor','none');
                                isonormals(Phi,Theta,Psi,V_N,P)
                            end
                        end
                    hold(hAx,'off')
                    
                    camlight 
                    camlight(-80,-10) 
                    lighting gouraud
                    
                    XLabel = '\phi';
                    YLabel = '\theta';
                    hAx.ZLabel.String = '\psi';
                    hAx.Box = 'on';
                    hAx.XLim = [0,360];
                    hAx.YLim = [0,180];
                    hAx.ZLim = [0,360];
                    IndexStr = app.SelectedIndex.Value;
                    TitleStr = [SpecType,' of selection #: ',IndexStr];
            end
            hAx.FontSize = 16;
            hAx.XLabel.String = XLabel;
            hAx.YLabel.String = YLabel;
            hAx.Title.String = TitleStr;
            view(hAx,3)
        end

        % Cell selection callback: UITable
        function UITable_CellSelection(app, event)
            RowInd = event.Indices(:,1);
            app.SelectedRow = RowInd;
            IndexStr = num2str(RowInd');
            IndexStr = regexprep(IndexStr,'\ \ ',',');
            app.SelectedIndex.Value = IndexStr;
        end

        % Value changed function: DropDown_SpectraType
        function DropDown_SpectraType_ValueChanged(app, event)
            ClearAll_ButtonPushed(app, event)
            Read_SpecData(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Orientation Analysis';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportData_ButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Region of selection')
            xlabel(app.UIAxes, 'Probe (cm^{-1})')
            ylabel(app.UIAxes, 'Pump (cm^{-1})')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; 'Intensity'; 'R max'; 'R min'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.SpectraTypeDropDownLabel = uilabel(app.UIFigure);
            app.SpectraTypeDropDownLabel.Text = 'Spectra Type';
            app.DropDown_SpectraType = uidropdown(app.UIFigure);
            app.DropDown_SpectraType.Items = {'SFG', '2DSFG'};
            app.DropDown_SpectraType.ValueChangedFcn = createCallbackFcn(app, @DropDown_SpectraType_ValueChanged, true);
            app.DropDown_SpectraType.Value = 'SFG';
            app.BrushDataPanel = uipanel(app.UIFigure);
            app.BrushDataPanel.Title = 'Brush Data';
            app.ReadBrushButton = uibutton(app.BrushDataPanel, 'push');
            app.ReadBrushButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBrush_ButtonPushed, true);
            app.ReadBrushButton.Text = 'Read Brush';
            app.DeleteRowButton = uibutton(app.BrushDataPanel, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRow_ButtonPushed, true);
            app.DeleteRowButton.Text = 'Delete Row';
            app.ClearallButton = uibutton(app.BrushDataPanel, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAll_ButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.IntensityPlotButton = uibutton(app.BrushDataPanel, 'push');
            app.IntensityPlotButton.ButtonPushedFcn = createCallbackFcn(app, @IntensityPlot_ButtonPushed, true);
            app.IntensityPlotButton.Text = 'Intensity Plot';
            app.OrientationMapPanel = uipanel(app.UIFigure);
            app.OrientationMapPanel.Title = 'Orientation Map';
            app.SelectedLabel = uilabel(app.OrientationMapPanel);
            app.SelectedLabel.Text = 'Selected';
            app.SelectedIndex = uieditfield(app.OrientationMapPanel, 'text');
            app.SelectedIndex.Value = 'none';
            app.GridEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.GridEditFieldLabel.Text = '# Grid';
            app.GridEditField = uieditfield(app.OrientationMapPanel, 'numeric');
            app.GridEditField.Value = 10;
            app.PhiSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.PhiSliceEditFieldLabel.Text = 'Phi Slice';
            app.PhiSliceEditField = uieditfield(app.OrientationMapPanel, 'text');
            app.PsiSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.PsiSliceEditFieldLabel.Text = 'Psi Slice';
            app.PsiSliceEditField = uieditfield(app.OrientationMapPanel, 'text');
            app.ThetaSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.ThetaSliceEditFieldLabel.Text = 'Theta Slice';
            app.ThetaSliceEditField = uieditfield(app.OrientationMapPanel, 'text');
            app.PhiContourSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.PhiContourSliceEditFieldLabel.Text = 'Phi Contour Slice';
            app.PhiContourSliceEditField = uieditfield(app.OrientationMapPanel, 'text');
            app.PsiContourSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.PsiContourSliceEditFieldLabel.Text = 'Psi Contour Slice';
            app.PsiContourSliceEditField = uieditfield(app.OrientationMapPanel, 'text');
            app.ThetaContourSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.ThetaContourSliceEditFieldLabel.Text = 'Theta Contour Slice';
            app.ThetaContourSliceEditField = uieditfield(app.OrientationMapPanel, 'text');
            app.IsovaluesEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.IsovaluesEditFieldLabel.Text = 'Isovalues';
            app.IsovaluesEditField = uieditfield(app.OrientationMapPanel, 'text');
            app.IsovaluesEditField.Value = '-0.5,0.5';
            app.V10Label = uilabel(app.UIFigure);
            app.V10Label.Text = 'V1.0';
            app.DrawMapButton = uibutton(app.UIFigure, 'push');
            app.DrawMapButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMap_ButtonPushed, true);
            app.DrawMapButton.Text = 'Draw Map';
            app.StyleDropDownLabel = uilabel(app.UIFigure);
            app.StyleDropDownLabel.Text = 'Style';
            app.StyleDropDown = uidropdown(app.UIFigure);
            app.StyleDropDown.Items = {'Slice', 'Theta-Psi', 'Theta-Phi'};
            app.StyleDropDown.Value = 'Slice';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OrientationAnalysis(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end