classdef Model_PDB_AmideOne < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel_Orientation            matlab.ui.container.Panel
        Button_LoadPDB               matlab.ui.control.Button
        CheckBox_MDsnapshots         matlab.ui.control.CheckBox
        MoleculeEditFieldLabel       matlab.ui.control.Label
        EditField_PDB_Name           matlab.ui.control.EditField
        PhiEditFieldLabel            matlab.ui.control.Label
        EditField_Phi                matlab.ui.control.NumericEditField
        PsiEditFieldLabel            matlab.ui.control.Label
        EditField_Psi                matlab.ui.control.NumericEditField
        ThetaEditFieldLabel          matlab.ui.control.Label
        EditField_Theta              matlab.ui.control.NumericEditField
        DegLabel                     matlab.ui.control.Label
        Panel_Freq                   matlab.ui.container.Panel
        LocalmodeFreqEditFieldLabel  matlab.ui.control.Label
        EditField_LocFreq            matlab.ui.control.NumericEditField
        LabeledFreq                  matlab.ui.control.Label
        EditField_LabelFreq          matlab.ui.control.NumericEditField
        AnharmonicityLabel           matlab.ui.control.Label
        EditField_Anharm             matlab.ui.control.NumericEditField
        LabelIndex                   matlab.ui.control.Label
        EditField_LabelInd           matlab.ui.control.EditField
        Panel_FigOpt                 matlab.ui.container.Panel
        CheckBox_Atom                matlab.ui.control.CheckBox
        CheckBox_Bond                matlab.ui.control.CheckBox
        CheckBox_Axis                matlab.ui.control.CheckBox
        CheckBox_Label               matlab.ui.control.CheckBox
        CheckBox_SideChain           matlab.ui.control.CheckBox
        Button_Generate              matlab.ui.control.Button
        Button_Draw                  matlab.ui.control.Button
        Button_ExportData            matlab.ui.control.Button
        Version                      matlab.ui.control.Label
    end


    properties (Access = public)
        Parent % handles of parent
        PDB % Organized PDB info
        Structure % molecule structre class
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                AssignParent(app,varargin)
            else
                disp('Running Model_PDB _Amide-I in the stand alone mode...')
            end
        end

        % Callback function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            Data_PDB_AmideOne.PDB       = app.PDB;
            Data_PDB_AmideOne.Structure = app.Structure;
            
            assignin('base','Data_PDB_AmideOne',Data_PDB_AmideOne)
            disp('Updated Data_PDB_AmideOne exported!')
        end

        % Button pushed function: Button_LoadPDB
        function Button_LoadPDBPushed(app, event)
            GUI_Inputs = Parse_PDB_AmideOne(app);
            app.PDB = Load_PDB(app,GUI_Inputs);
            app.EditField_PDB_Name.Value = app.PDB.FilesName;
        end

        % Button pushed function: Button_Generate
        function Button_GeneratePushed(app, event)
            if isempty(app.PDB)
                Button_LoadPDBPushed(app, event)
            end
            
            GUI_Inputs = Parse_PDB_AmideOne(app);
            app.Structure = Build_PDB_AmideI_Structure(app.PDB,GUI_Inputs);
            
            % export necessary handle and functions
            app.Structure.hPlotFunc = @PlotXYZfiles_AmideI;
            app.Structure.hParseGUIFunc = @Parse_PDB_AmideOne;
            app.Structure.hGUIs = app;
            
            % Export into Structure so it can be passsed around different GUIs
            app.Structure.StructModel = 2; 
            
            % update to COSMOSS
            Export2GUIs(app)
            
            disp('Structure file generated!')
        end

        % Button pushed function: Button_Draw
        function Button_DrawPushed(app, event)
            if isempty(app.Structure)
                Button_GeneratePushed(app, event)
            end
            hF = app.Structure.Draw;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Model PDB Amide-I';
            app.Panel_Orientation = uipanel(app.UIFigure);
            app.Panel_Orientation.Title = 'Molecule & Orientation';
            app.Button_LoadPDB = uibutton(app.Panel_Orientation, 'push');
            app.Button_LoadPDB.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadPDBPushed, true);
            app.Button_LoadPDB.Text = 'Load PDB';
            app.CheckBox_MDsnapshots = uicheckbox(app.Panel_Orientation);
            app.CheckBox_MDsnapshots.Text = 'MD snapshots';
            app.MoleculeEditFieldLabel = uilabel(app.Panel_Orientation);
            app.MoleculeEditFieldLabel.Text = 'Molecule:';
            app.EditField_PDB_Name = uieditfield(app.Panel_Orientation, 'text');
            app.EditField_PDB_Name.Editable = 'off';
            app.EditField_PDB_Name.Value = 'No PDB is loaded...';
            app.PhiEditFieldLabel = uilabel(app.Panel_Orientation);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.EditField_Phi = uieditfield(app.Panel_Orientation, 'numeric');
            app.PsiEditFieldLabel = uilabel(app.Panel_Orientation);
            app.PsiEditFieldLabel.Text = 'Psi';
            app.EditField_Psi = uieditfield(app.Panel_Orientation, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.Panel_Orientation);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.EditField_Theta = uieditfield(app.Panel_Orientation, 'numeric');
            app.DegLabel = uilabel(app.Panel_Orientation);
            app.DegLabel.Text = 'Deg.';
            app.Panel_Freq = uipanel(app.UIFigure);
            app.Panel_Freq.Title = 'Freq. & Anharmonicy (cm-1)';
            app.LocalmodeFreqEditFieldLabel = uilabel(app.Panel_Freq);
            app.LocalmodeFreqEditFieldLabel.Text = 'Local mode Freq.:';
            app.EditField_LocFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.EditField_LocFreq.Value = 1650;
            app.LabeledFreq = uilabel(app.Panel_Freq);
            app.LabeledFreq.Text = 'Labeled Freq.:';
            app.EditField_LabelFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.EditField_LabelFreq.Value = 1630;
            app.AnharmonicityLabel = uilabel(app.Panel_Freq);
            app.AnharmonicityLabel.Text = 'Anharmonicity:';
            app.EditField_Anharm = uieditfield(app.Panel_Freq, 'numeric');
            app.EditField_Anharm.Value = 10;
            app.LabelIndex = uilabel(app.Panel_Freq);
            app.LabelIndex.Text = 'Label Index:';
            app.EditField_LabelInd = uieditfield(app.Panel_Freq, 'text');
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure options';
            app.CheckBox_Atom = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Atom.Text = 'Atom';
            app.CheckBox_Atom.Value = true;
            app.CheckBox_Bond = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Bond.Text = 'Bond';
            app.CheckBox_Bond.Value = true;
            app.CheckBox_Axis = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Axis.Text = 'Axis';
            app.CheckBox_Axis.Value = true;
            app.CheckBox_Label = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Label.Text = 'Label';
            app.CheckBox_Label.Value = true;
            app.CheckBox_SideChain = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_SideChain.Text = 'Side Chain';
            app.CheckBox_SideChain.Value = true;
            app.Button_Generate = uibutton(app.UIFigure, 'push');
            app.Button_Generate.ButtonPushedFcn = createCallbackFcn(app, @Button_GeneratePushed, true);
            app.Button_Generate.Text = 'Generate';
            app.Button_Draw = uibutton(app.UIFigure, 'push');
            app.Button_Draw.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawPushed, true);
            app.Button_Draw.Text = 'Draw';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V1.0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Model_PDB_AmideOne(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end