classdef Model_Betasheet_AmideI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel_BSheet             matlab.ui.container.Panel
        StrandTypeDropDownLabel  matlab.ui.control.Label
        SheetType                matlab.ui.control.DropDown
        residuesEditFieldLabel   matlab.ui.control.Label
        N_Residue                matlab.ui.control.NumericEditField
        strandsEditFieldLabel    matlab.ui.control.Label
        N_Strand                 matlab.ui.control.NumericEditField
        UITable_Dist             matlab.ui.control.Table
        UITable_Angle            matlab.ui.control.Table
        Panel_FigOpt             matlab.ui.container.Panel
        CheckBox_Atom            matlab.ui.control.CheckBox
        CheckBox_Bond            matlab.ui.control.CheckBox
        CheckBox_Axis            matlab.ui.control.CheckBox
        CheckBox_Label           matlab.ui.control.CheckBox
        Button_Generate          matlab.ui.control.Button
        Button_Draw              matlab.ui.control.Button
        Button_ExportData        matlab.ui.control.Button
        Version                  matlab.ui.control.Label
        Panel_Freq               matlab.ui.container.Panel
        LabeledFreqcm1           matlab.ui.control.Label
        LabelFreq                matlab.ui.control.NumericEditField
        LabelIndex               matlab.ui.control.Label
        LabelInd                 matlab.ui.control.EditField
        ModeFreqcm1Label         matlab.ui.control.Label
        LocFreq                  matlab.ui.control.NumericEditField
        FreqModificationLabel    matlab.ui.control.Label
        DropDown_LocFreq         matlab.ui.control.DropDown
        Anharmonicitycm1Label    matlab.ui.control.Label
        Anharm                   matlab.ui.control.NumericEditField
        Panel_Coupling           matlab.ui.container.Panel
        ModelLabel               matlab.ui.control.Label
        DropDown_Coupling        matlab.ui.control.DropDown
        BetaNNcm1Label           matlab.ui.control.Label
        EditField_NN             matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        ParentGUI   % GUI handle of the parent GUI
        fileChooser % a place to keep file chooser handle
        Structure   % molecule structre class % Description
        CombOrder   % The sequence number order from comb2
        GUI_Tag     % Tag for GUI recognition
    end

    methods (Access = private)
        
        function Init(app)
            app.GUI_Tag = 'BSheet';
            Dist_Table = {'Strand trans.',0,0,4.75};
            Dist_ColumnFormat   = {    'char','bank','bank', 'bank'};
            
            app.UITable_Dist.Data           = Dist_Table;
            app.UITable_Dist.ColumnFormat   = Dist_ColumnFormat;
            
            
            Angle_Table = {'Strand twist',0,0,0;...
                           'Lab rotation',0,0,0};
            Angle_ColumnFormat   = {    'char','bank','bank', 'bank'};
            
            app.UITable_Angle.Data           = Angle_Table;
            app.UITable_Angle.ColumnFormat   = Angle_ColumnFormat;

            % Setup Coupling model items
            [~,CouplingList] = Coupling('','List','');
            app.DropDown_Coupling.Items = CouplingList;
            
            %% Add Properties Change Listner
            SA = [...
                  app.SheetType,...
                  app.N_Residue,...
                  app.N_Strand,...
                  app.LocFreq,...
                  app.LabelFreq,...
                  app.LabelInd,...
                  app.Anharm,...
                  app.CheckBox_Atom,...
                  app.CheckBox_Bond,...
                  app.CheckBox_Axis,...
                  app.CheckBox_Label,...
                  ];
            
            for i = 1:length(SA)
                SA(i).ValueChangedFcn = @(~,~)RefreshSD(app);
            end
            
            app.UITable_Dist.CellEditCallback  = @(~,~)RefreshSD(app);
            app.UITable_Angle.CellEditCallback = @(~,~)RefreshSD(app);
            
        end
        
    end
    
    methods (Access = public)

        function O = Parse_GUI(app)
            % Orientation -------------------------------------------------------------
            O.SheetType = app.SheetType.Value;
            O.N_Residue = app.N_Residue.Value;
            O.N_Strand  = app.N_Strand.Value ;
            
            Table_Dist  = app.UITable_Dist.Data;
            O.Trans_X   = Table_Dist{1,2};
            O.Trans_Y   = Table_Dist{1,3};
            O.Trans_Z   = Table_Dist{1,4};
            
            Table_Angle = app.UITable_Angle.Data;
            O.Twist_X   = Table_Angle{1,2};
            O.Twist_Y   = Table_Angle{1,3};
            O.Twist_Z   = Table_Angle{1,4};
            O.Phi_D     = Table_Angle{2,2};
            O.Psi_D     = Table_Angle{2,3};
            O.Theta_D   = Table_Angle{2,4};
                    
            % For Local Mode frequencies ----------------------------------------------
            O.LocFreqType = app.DropDown_LocFreq.Value;
            O.NLFreq      = app.LocFreq.Value;
            O.Anharm      = app.Anharm.Value;
            O.LFreq       = app.LabelFreq.Value;
            O.L_Index     = str2num(app.LabelInd.Value);
            
            % Coupling model -----------------------------------------------------------
            O.CouplingType = app.DropDown_Coupling.Value;
            O.Beta_NN      = app.EditField_NN.Value;
            
            % For molecule ploting ----------------------------
            O.Plot_Atoms     = app.CheckBox_Atom.Value;
            O.Plot_Bonds     = app.CheckBox_Bond.Value;
            O.Plot_Axis      = app.CheckBox_Axis.Value;
            O.Plot_Label     = app.CheckBox_Label.Value;
            % -------------------------------------------------
        end
        
        function RefreshSD(app)
            Button_GeneratePushed(app,'');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                ParentInfo = varargin;
            else
                ParentInfo = {'StandAlong'};
            end
            AssignParent(app,ParentInfo)
            
            % initialize the orientation table
            Init(app)
        end

        % Button pushed function: Button_Generate
        function Button_GeneratePushed(app, event)
            GUI_Inputs = Parse_GUI(app);
            S_BSheet   = ConstuctBetaSheet(GUI_Inputs);
           
            % update StructureData to GUIs
            app.Structure = S_BSheet;
            Export2Parent(app)
        end

        % Button pushed function: Button_Draw
        function Button_DrawPushed(app, event)
            app.Structure.SD_Draw;
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            ExportGUIData(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Model Betasheet';
            app.Panel_BSheet = uipanel(app.UIFigure);
            app.Panel_BSheet.Title = 'Betasheet parameters';
            app.StrandTypeDropDownLabel = uilabel(app.Panel_BSheet);
            app.StrandTypeDropDownLabel.Text = 'Strand Type';
            app.SheetType = uidropdown(app.Panel_BSheet);
            app.SheetType.Items = {'Parallel', 'Anti-Parallel'};
            app.SheetType.Value = 'Anti-Parallel';
            app.residuesEditFieldLabel = uilabel(app.Panel_BSheet);
            app.residuesEditFieldLabel.Text = '# residues';
            app.N_Residue = uieditfield(app.Panel_BSheet, 'numeric');
            app.N_Residue.Value = 5;
            app.strandsEditFieldLabel = uilabel(app.Panel_BSheet);
            app.strandsEditFieldLabel.Text = '# strands';
            app.N_Strand = uieditfield(app.Panel_BSheet, 'numeric');
            app.N_Strand.Value = 3;
            app.UITable_Dist = uitable(app.Panel_BSheet);
            app.UITable_Dist.ColumnName = {'Distance'; 'X'; 'Y'; 'Z'};
            app.UITable_Dist.RowName = {};
            app.UITable_Dist.ColumnEditable = [false true true true];
            app.UITable_Angle = uitable(app.Panel_BSheet);
            app.UITable_Angle.ColumnName = {'Angle'; 'Phi'; 'Psi'; 'Theta'};
            app.UITable_Angle.RowName = {};
            app.UITable_Angle.ColumnEditable = [false true true true];
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure options';
            app.CheckBox_Atom = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Atom.Text = 'Atom';
            app.CheckBox_Atom.Value = true;
            app.CheckBox_Bond = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Bond.Text = 'Bond';
            app.CheckBox_Bond.Value = true;
            app.CheckBox_Axis = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Axis.Text = 'Axis';
            app.CheckBox_Axis.Value = true;
            app.CheckBox_Label = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Label.Text = 'Label';
            app.CheckBox_Label.Value = true;
            app.Button_Generate = uibutton(app.UIFigure, 'push');
            app.Button_Generate.ButtonPushedFcn = createCallbackFcn(app, @Button_GeneratePushed, true);
            app.Button_Generate.Text = 'Generate';
            app.Button_Draw = uibutton(app.UIFigure, 'push');
            app.Button_Draw.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawPushed, true);
            app.Button_Draw.Text = 'Draw';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V1.3';
            app.Panel_Freq = uipanel(app.UIFigure);
            app.Panel_Freq.Title = 'Local mode setting';
            app.LabeledFreqcm1 = uilabel(app.Panel_Freq);
            app.LabeledFreqcm1.Text = 'Labeled Freq. (cm-1)';
            app.LabelFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LabelFreq.Value = 1630;
            app.LabelIndex = uilabel(app.Panel_Freq);
            app.LabelIndex.Text = 'Label Index:';
            app.LabelInd = uieditfield(app.Panel_Freq, 'text');
            app.ModeFreqcm1Label = uilabel(app.Panel_Freq);
            app.ModeFreqcm1Label.Text = 'Mode Freq. (cm-1)';
            app.LocFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LocFreq.Value = 1650;
            app.FreqModificationLabel = uilabel(app.Panel_Freq);
            app.FreqModificationLabel.Text = 'Freq. Modification';
            app.DropDown_LocFreq = uidropdown(app.Panel_Freq);
            app.DropDown_LocFreq.Items = {'None', 'Jensen Map'};
            app.DropDown_LocFreq.Value = 'None';
            app.Anharmonicitycm1Label = uilabel(app.Panel_Freq);
            app.Anharmonicitycm1Label.Text = 'Anharmonicity (cm-1)';
            app.Anharm = uieditfield(app.Panel_Freq, 'numeric');
            app.Anharm.Value = 10;
            app.Panel_Coupling = uipanel(app.UIFigure);
            app.Panel_Coupling.Title = 'Coupling Model';
            app.ModelLabel = uilabel(app.Panel_Coupling);
            app.ModelLabel.Text = 'Model';
            app.DropDown_Coupling = uidropdown(app.Panel_Coupling);
            app.DropDown_Coupling.Items = {};
            app.DropDown_Coupling.Value = {};
            app.BetaNNcm1Label = uilabel(app.Panel_Coupling);
            app.BetaNNcm1Label.Text = 'Beta NN (cm-1)';
            app.EditField_NN = uieditfield(app.Panel_Coupling, 'numeric');
            app.EditField_NN.Value = 0.8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_Betasheet_AmideI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end